classdef FaultClassifierApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        PredictButton  matlab.ui.control.Button
        StatusLabel    matlab.ui.control.Label
        IcSlider       matlab.ui.control.Slider
        IcSliderLabel  matlab.ui.control.Label
        IbSlider       matlab.ui.control.Slider
        IbSliderLabel  matlab.ui.control.Label
        IaSlider       matlab.ui.control.Slider
        IaSliderLabel  matlab.ui.control.Label
        VcSlider       matlab.ui.control.Slider
        VcSliderLabel  matlab.ui.control.Label
        VbSlider       matlab.ui.control.Slider
        VbSliderLabel  matlab.ui.control.Label
        VaSlider       matlab.ui.control.Slider
        VaSliderLabel  matlab.ui.control.Label
        UIAxes         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        TrainedModel % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           if exist('trained_fault_model.mat', 'file')
                data = load('trained_fault_model.mat');
                app.TrainedModel = data.mdl;
            else
                uialert(app.UIFigure, 'trained_fault_model.mat not found! Run train_classifier.m first.', 'File Missing');
            end
       
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            delete(app);
        end

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
            if isempty(app.TrainedModel)
                return;
            end

            % Read current slider values from the UI
            features = [app.VaSlider.Value, app.VbSlider.Value, app.VcSlider.Value, ...
                        app.IaSlider.Value, app.IbSlider.Value, app.IcSlider.Value];

            % Run ML Prediction
            [pred_class, scores] = predict(app.TrainedModel, features);

            classes = {'Normal Operation', 'Line-to-Ground (LG)', ...
                       'Line-to-Line (LL)', 'Double Line-to-Ground (LLG)', 'Three-Phase (LLL)'};

            % Update Status Text Label
            app.StatusLabel.Text = sprintf('Predicted: %s (Confidence: %.1f%%)', ...
                classes{pred_class}, max(scores) * 100);

            if pred_class == 1
                app.StatusLabel.FontColor = [0 0.6 0]; % Green for Normal
            else
                app.StatusLabel.FontColor = [0.8 0 0]; % Red for Fault
            end

            % Plot Class Probabilities on UIAxes (Fixed property name: UIAxes)
            bar(app.UIAxes, scores);
            app.UIAxes.XTickLabel = {'Normal', 'LG', 'LL', 'LLG', 'LLL'};
            app.UIAxes.YLim = [0 1];
            grid(app.UIAxes, 'on');
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'ProbabilityAxes')
            xlabel(app.UIAxes, 'Fault Class')
            ylabel(app.UIAxes, 'Probability')
            zlabel(app.UIAxes, 'Z')
            app.VaSliderLabel = uilabel(app.UIFigure);
            app.VaSliderLabel.Text = 'VaSlider';
            app.VaSlider = uislider(app.UIFigure);
            app.VaSlider.Limits = [0 1.5];
            app.VaSlider.Value = 1;
            app.VbSliderLabel = uilabel(app.UIFigure);
            app.VbSliderLabel.Text = 'VbSlider';
            app.VbSlider = uislider(app.UIFigure);
            app.VbSlider.Limits = [0 1.5];
            app.VbSlider.Value = 1;
            app.VcSliderLabel = uilabel(app.UIFigure);
            app.VcSliderLabel.Text = 'VcSlider';
            app.VcSlider = uislider(app.UIFigure);
            app.VcSlider.Limits = [0 1.5];
            app.VcSlider.Value = 1;
            app.IaSliderLabel = uilabel(app.UIFigure);
            app.IaSliderLabel.Text = 'IaSlider';
            app.IaSlider = uislider(app.UIFigure);
            app.IaSlider.Limits = [0 10];
            app.IaSlider.Value = 1;
            app.IbSliderLabel = uilabel(app.UIFigure);
            app.IbSliderLabel.Text = 'IbSlider';
            app.IbSlider = uislider(app.UIFigure);
            app.IbSlider.Limits = [0 10];
            app.IbSlider.Value = 1;
            app.IcSliderLabel = uilabel(app.UIFigure);
            app.IcSliderLabel.Text = 'IcSlider';
            app.IcSlider = uislider(app.UIFigure);
            app.IcSlider.Limits = [0 10];
            app.IcSlider.Value = 1;
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'StatusLabel';
            app.PredictButton = uibutton(app.UIFigure, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = 'PredictButton';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FaultClassifierApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end